classdef EV3SimGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECE756EV3GUIUIFigure            matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TrackImage                      matlab.ui.control.Image
        EVControlsPanel                 matlab.ui.container.Panel
        LeaderButtonGroup               matlab.ui.container.ButtonGroup
        EV1_LeaderButton                matlab.ui.control.RadioButton
        EV2_LeaderButton                matlab.ui.control.RadioButton
        EV3_LeaderButton                matlab.ui.control.RadioButton
        EV4_LeaderButton                matlab.ui.control.RadioButton
        NoteallothersarefollowersLabel  matlab.ui.control.Label
        EV1_DriveButton                 matlab.ui.control.Button
        EV1_StopButton                  matlab.ui.control.Button
        EV1_ParkButton                  matlab.ui.control.Button
        EV1_PlaceButton                 matlab.ui.control.Button
        AllEV_ControlLabel              matlab.ui.control.Label
        EV1_ControlLabel                matlab.ui.control.Label
        AllEV_DriveButton               matlab.ui.control.Button
        AllEV_StopButton                matlab.ui.control.Button
        AllEV_ParkButton                matlab.ui.control.Button
        EV2_DriveButton                 matlab.ui.control.Button
        EV2_StopButton                  matlab.ui.control.Button
        EV2_ParkButton                  matlab.ui.control.Button
        EV2_PlaceButton                 matlab.ui.control.Button
        EV2_ControlLabel                matlab.ui.control.Label
        EV3_DriveButton                 matlab.ui.control.Button
        EV3_StopButton                  matlab.ui.control.Button
        EV3_ParkButton                  matlab.ui.control.Button
        EV3_PlaceButton                 matlab.ui.control.Button
        EV3_ControlLabel                matlab.ui.control.Label
        EV4_DriveButton                 matlab.ui.control.Button
        EV4_StopButton                  matlab.ui.control.Button
        EV4_ParkButton                  matlab.ui.control.Button
        EV4_PlaceButton                 matlab.ui.control.Button
        EV4_ControlLabel                matlab.ui.control.Label
        EV5_DriveButton                 matlab.ui.control.Button
        EV5_StopButton                  matlab.ui.control.Button
        EV5_ParkButton                  matlab.ui.control.Button
        EV5_PlaceButton                 matlab.ui.control.Button
        EV5_ControlLabel                matlab.ui.control.Label
        EV2_DriveLamp                   matlab.ui.control.Lamp
        EV2_StopLamp                    matlab.ui.control.Lamp
        EV2_ParkLamp                    matlab.ui.control.Lamp
        EV4_DriveLamp                   matlab.ui.control.Lamp
        EV4_StopLamp                    matlab.ui.control.Lamp
        EV4_ParkLamp                    matlab.ui.control.Lamp
        EV1_DriveLamp                   matlab.ui.control.Lamp
        EV1_StopLamp                    matlab.ui.control.Lamp
        EV1_ParkLamp                    matlab.ui.control.Lamp
        EV3_DriveLamp                   matlab.ui.control.Lamp
        EV3_StopLamp                    matlab.ui.control.Lamp
        EV3_ParkLamp                    matlab.ui.control.Lamp
        EV5_DriveLamp                   matlab.ui.control.Lamp
        EV5_StopLamp                    matlab.ui.control.Lamp
        EV5_ParkLamp                    matlab.ui.control.Lamp
        ResetAllButton                  matlab.ui.control.Button
        EVScoresPanel                   matlab.ui.container.Panel
        EV1_ScoresLabel                 matlab.ui.control.Label
        EV2_ScoresLabel                 matlab.ui.control.Label
        EV3_ScoresLabel                 matlab.ui.control.Label
        EV4_ScoresLabel                 matlab.ui.control.Label
        EV1_C1                          matlab.ui.control.NumericEditField
        EV1_C1Label                     matlab.ui.control.Label
        EV1_C3                          matlab.ui.control.NumericEditField
        EV1_C3Label                     matlab.ui.control.Label
        EV1_C2                          matlab.ui.control.NumericEditField
        EV1_C2Label                     matlab.ui.control.Label
        EV2_C1                          matlab.ui.control.NumericEditField
        EV2_C1Label                     matlab.ui.control.Label
        EV2_C3                          matlab.ui.control.NumericEditField
        EV2_C3Label                     matlab.ui.control.Label
        EV2_C2                          matlab.ui.control.NumericEditField
        EV2_C2Label                     matlab.ui.control.Label
        EV3_C1                          matlab.ui.control.NumericEditField
        EV3_C1Label                     matlab.ui.control.Label
        EV3_C3                          matlab.ui.control.NumericEditField
        EV3_C3Label                     matlab.ui.control.Label
        EV3_C2                          matlab.ui.control.NumericEditField
        EV3_C2Label                     matlab.ui.control.Label
        EV4_C1                          matlab.ui.control.NumericEditField
        EV4_C1Label                     matlab.ui.control.Label
        EV4_C3                          matlab.ui.control.NumericEditField
        EV4_C3Label                     matlab.ui.control.Label
        EV4_C2                          matlab.ui.control.NumericEditField
        EV4_C2Label                     matlab.ui.control.Label
        EV5_ScoresLabel                 matlab.ui.control.Label
        EV5_C1                          matlab.ui.control.NumericEditField
        EV5_C1Label                     matlab.ui.control.Label
        EV5_C3                          matlab.ui.control.NumericEditField
        EV5_C3Label                     matlab.ui.control.Label
        EV5_C2                          matlab.ui.control.NumericEditField
        EV5_C2Label                     matlab.ui.control.Label
        EVPositionsPanel                matlab.ui.container.Panel
        EV1_PositionsLabel              matlab.ui.control.Label
        EV2_PositionsLabel              matlab.ui.control.Label
        EV3_PositionsLabel              matlab.ui.control.Label
        EV4_PositionsLabel              matlab.ui.control.Label
        EV1_X                           matlab.ui.control.NumericEditField
        EV1_XmmLabel                    matlab.ui.control.Label
        EV1_Y                           matlab.ui.control.NumericEditField
        EV1_YmmLabel                    matlab.ui.control.Label
        EV1_TH                          matlab.ui.control.NumericEditField
        EV1_YawdegLabel                 matlab.ui.control.Label
        EV2_X                           matlab.ui.control.NumericEditField
        EV2_XmmLabel                    matlab.ui.control.Label
        EV2_Y                           matlab.ui.control.NumericEditField
        EV2_YmmLabel                    matlab.ui.control.Label
        EV2_TH                          matlab.ui.control.NumericEditField
        EV2_YawdegLabel                 matlab.ui.control.Label
        EV3_X                           matlab.ui.control.NumericEditField
        EV3_XmmLabel                    matlab.ui.control.Label
        EV3_Y                           matlab.ui.control.NumericEditField
        EV3_YmmLabel                    matlab.ui.control.Label
        EV3_TH                          matlab.ui.control.NumericEditField
        EV3_YawdegLabel                 matlab.ui.control.Label
        EV4_X                           matlab.ui.control.NumericEditField
        EV4_XmmLabel                    matlab.ui.control.Label
        EV4_Y                           matlab.ui.control.NumericEditField
        EV4_YmmLabel                    matlab.ui.control.Label
        EV4_TH                          matlab.ui.control.NumericEditField
        EV4_YawdegLabel                 matlab.ui.control.Label
        EV5_PositionsLabel              matlab.ui.control.Label
        EV5_X                           matlab.ui.control.NumericEditField
        EV5_XmmLabel                    matlab.ui.control.Label
        EV5_Y                           matlab.ui.control.NumericEditField
        EV5_YmmLabel                    matlab.ui.control.Label
        EV5_TH                          matlab.ui.control.NumericEditField
        EV5_YawdegLabel                 matlab.ui.control.Label
        EV1_SensorsPanel                matlab.ui.container.Panel
        RightRLILabel_4                 matlab.ui.control.Label
        EV1_RightRLIGauge               matlab.ui.control.LinearGauge
        LeftRLILabel_4                  matlab.ui.control.Label
        EV1_LeftRLIGauge                matlab.ui.control.LinearGauge
        UltrasonicLabel                 matlab.ui.control.Label
        EV1_UltrasonicGauge             matlab.ui.control.LinearGauge
        EV1_UltrasonicValue             matlab.ui.control.Label
        EV1_LeftRLIValue                matlab.ui.control.Label
        EV1_RightRLIValue               matlab.ui.control.Label
        BatteryGaugeLabel               matlab.ui.control.Label
        EV1_BatteryGauge                matlab.ui.control.LinearGauge
        EV1_mVLabel                     matlab.ui.control.Label
        EV1_cmLabel                     matlab.ui.control.Label
        EV1_PercentLabel2               matlab.ui.control.Label
        EV1_PercentLabel1               matlab.ui.control.Label
        EV2_SensorsPanel                matlab.ui.container.Panel
        RightRLILabel_5                 matlab.ui.control.Label
        EV2_RightRLIGauge               matlab.ui.control.LinearGauge
        LeftRLILabel_5                  matlab.ui.control.Label
        EV2_LeftRLIGauge                matlab.ui.control.LinearGauge
        UltrasonicLabel_2               matlab.ui.control.Label
        EV2_UltrasonicGauge             matlab.ui.control.LinearGauge
        EV2_UltrasonicValue             matlab.ui.control.Label
        EV2_LeftRLIValue                matlab.ui.control.Label
        EV2_RightRLIValue               matlab.ui.control.Label
        BatteryGauge_2Label             matlab.ui.control.Label
        EV2_BatteryGauge                matlab.ui.control.LinearGauge
        EV2_mVLabel                     matlab.ui.control.Label
        EV2_cmLabel                     matlab.ui.control.Label
        EV2_PercentLabel2               matlab.ui.control.Label
        EV2_PercentLabel1               matlab.ui.control.Label
        EV3_SensorsPanel                matlab.ui.container.Panel
        RightRLILabel_6                 matlab.ui.control.Label
        EV3_RightRLIGauge               matlab.ui.control.LinearGauge
        LeftRLILabel_6                  matlab.ui.control.Label
        EV3_LeftRLIGauge                matlab.ui.control.LinearGauge
        UltrasonicLabel_3               matlab.ui.control.Label
        EV3_UltrasonicGauge             matlab.ui.control.LinearGauge
        EV3_UltrasonicValue             matlab.ui.control.Label
        EV3_LeftRLIValue                matlab.ui.control.Label
        EV3_RightRLIValue               matlab.ui.control.Label
        BatteryGauge_3Label             matlab.ui.control.Label
        EV3_BatteryGauge                matlab.ui.control.LinearGauge
        EV3_mVLabel                     matlab.ui.control.Label
        EV3_cmLabel                     matlab.ui.control.Label
        EV3_PercentLabel2               matlab.ui.control.Label
        EV3_PercentLabel1               matlab.ui.control.Label
        EV4_SensorsPanel                matlab.ui.container.Panel
        RightRLILabel_7                 matlab.ui.control.Label
        EV4_RightRLIGauge               matlab.ui.control.LinearGauge
        LeftRLILabel_7                  matlab.ui.control.Label
        EV4_LeftRLIGauge                matlab.ui.control.LinearGauge
        UltrasonicLabel_4               matlab.ui.control.Label
        EV4_UltrasonicGauge             matlab.ui.control.LinearGauge
        EV4_UltrasonicValue             matlab.ui.control.Label
        EV4_LeftRLIValue                matlab.ui.control.Label
        EV4_RightRLIValue               matlab.ui.control.Label
        BatteryGauge_4Label             matlab.ui.control.Label
        EV4_BatteryGauge                matlab.ui.control.LinearGauge
        EV4_mVLabel                     matlab.ui.control.Label
        EV4_cmLabel                     matlab.ui.control.Label
        EV4_PercentLabel2               matlab.ui.control.Label
        EV4_PercentLabel1               matlab.ui.control.Label
        EV5_SensorsPanel                matlab.ui.container.Panel
        RightRLILabel_8                 matlab.ui.control.Label
        EV5_RightRLIGauge               matlab.ui.control.LinearGauge
        LeftRLILabel_8                  matlab.ui.control.Label
        EV5_LeftRLIGauge                matlab.ui.control.LinearGauge
        UltrasonicLabel_5               matlab.ui.control.Label
        EV5_UltrasonicGauge             matlab.ui.control.LinearGauge
        EV5_UltrasonicValue             matlab.ui.control.Label
        EV5_LeftRLIValue                matlab.ui.control.Label
        EV5_RightRLIValue               matlab.ui.control.Label
        BatteryGauge_5Label             matlab.ui.control.Label
        EV5_BatteryGauge                matlab.ui.control.LinearGauge
        EV5_mVLabel                     matlab.ui.control.Label
        EV5_cmLabel                     matlab.ui.control.Label
        EV5_PercentLabel2               matlab.ui.control.Label
        EV5_PercentLabel1               matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Background timer that forces more frequent GUI updates
        uiTimer = timer('StartDelay', 2, 'Period', .05, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
        last_draw_time = tic;
        updating = false;
        
        EV1_image;
        EV2_image;
        EV3_image;
        EV4_image;
        EV5_image;
        
        % EV objects to receive vehicle data from Simulink
        EV1;
        EV2;
        EV3;
        
        track_xscale = 749 / 4.85; % x pixels / x length meters
        track_yscale = 562 / 3.739133; % y pixels / y length meters
        track_xoffset = 182; % x pixel at bottom left of track
        track_yoffset = 10; % y pixel at bottom left of track
        track_EV_xoffset = 12;
        track_EV_yoffset = 12;
        placingEV1 = false; % flags used to manually place the vehicle on the graph
        placingEV2 = false;
        placingEV3 = false;
        placingEV4 = false;
    end
    
    methods (Access = private)
        % Executed every timer tick. Used to force the GUI to update with
        % new data.
        function timerTick(app)
            disp(toc(app.last_draw_time)) % Time measurement for debugging
            app.last_draw_time = tic;
            if ~app.updating % Basically, update GUI as fast as possible but skip updates if things are slow
                app.updating = true;
                updateEV1Displays(app);
                updateEV2Displays(app);
                updateEV3Displays(app);
                updateTrack(app);
                app.updating = false;
            end
        end
        
        % Updates gages and labels for EV1 with data from Simulink
        function updateEV1Displays(app)
            % Get data from EV object
            x          = app.EV1.x;
            y          = app.EV1.y;
            th         = app.EV1.th;
            rightRLI   = app.EV1.rightRLI;
            leftRLI    = app.EV1.leftRLI;
            ultrasonic = app.EV1.ultrasonic;
            battery    = app.EV1.battery;
            C1         = app.EV1.C1;
            C2         = app.EV1.C2;
            C3         = app.EV1.C3;
            
            % Update GUI data
            app.EV1_X.Value               = x;
            app.EV1_Y.Value               = y;
            app.EV1_TH.Value              = th;
            app.EV1_RightRLIGauge.Value   = rightRLI;
            app.EV1_RightRLIValue.Text    = string(round(rightRLI));
            app.EV1_LeftRLIGauge.Value    = leftRLI;
            app.EV1_LeftRLIValue.Text     = string(round(leftRLI));
            app.EV1_UltrasonicGauge.Value = ultrasonic;
            app.EV1_UltrasonicValue.Text  = string(round(ultrasonic));
            app.EV1_BatteryGauge.Value    = battery;
            app.EV1_C1.Value              = C1;
            app.EV1_C2.Value              = C2;
            app.EV1_C3.Value              = C3;
        end
        
        % Updates gages and labels for EV2 with data from Simulink
        function updateEV2Displays(app)
            % Get data from EV object
            x          = app.EV2.x;
            y          = app.EV2.y;
            th         = app.EV2.th;
            rightRLI   = app.EV2.rightRLI;
            leftRLI    = app.EV2.leftRLI;
            ultrasonic = app.EV2.ultrasonic;
            battery    = app.EV2.battery;
            C1         = app.EV2.C1;
            C2         = app.EV2.C2;
            C3         = app.EV2.C3;
            
            % Update GUI data
            app.EV2_X.Value               = x;
            app.EV2_Y.Value               = y;
            app.EV2_TH.Value              = th;
            app.EV2_RightRLIGauge.Value   = rightRLI;
            app.EV2_RightRLIValue.Text    = string(round(rightRLI));
            app.EV2_LeftRLIGauge.Value    = leftRLI;
            app.EV2_LeftRLIValue.Text     = string(round(leftRLI));
            app.EV2_UltrasonicGauge.Value = ultrasonic;
            app.EV2_UltrasonicValue.Text  = string(round(ultrasonic));
            app.EV2_BatteryGauge.Value    = battery;
            app.EV2_C1.Value              = C1;
            app.EV2_C2.Value              = C2;
            app.EV2_C3.Value              = C3;
        end
        
        % Updates gages and labels for EV3 with data from Simulink
        function updateEV3Displays(app)
            % Get data from EV object
            x          = app.EV3.x;
            y          = app.EV3.y;
            th         = app.EV3.th;
            rightRLI   = app.EV3.rightRLI;
            leftRLI    = app.EV3.leftRLI;
            ultrasonic = app.EV3.ultrasonic;
            battery    = app.EV3.battery;
            C1         = app.EV3.C1;
            C2         = app.EV3.C2;
            C3         = app.EV3.C3;
            
            % Update GUI data
            app.EV3_X.Value               = x;
            app.EV3_Y.Value               = y;
            app.EV3_TH.Value              = th;
            app.EV3_RightRLIGauge.Value   = rightRLI;
            app.EV3_RightRLIValue.Text    = string(round(rightRLI));
            app.EV3_LeftRLIGauge.Value    = leftRLI;
            app.EV3_LeftRLIValue.Text     = string(round(leftRLI));
            app.EV3_UltrasonicGauge.Value = ultrasonic;
            app.EV3_UltrasonicValue.Text  = string(round(ultrasonic));
            app.EV3_BatteryGauge.Value    = battery;
            app.EV3_C1.Value              = C1;
            app.EV3_C2.Value              = C2;
            app.EV3_C3.Value              = C3;
        end
        
        % Update vehicle positions on the track
        function updateTrack(app)
            % Get vehicle positions EV objects
            x_EV1  = app.EV1.x / 1000.0;
            y_EV1  = app.EV1.y / 1000.0;
            th_EV1 = app.EV1.th;
            x_EV2  = app.EV2.x / 1000.0;
            y_EV2  = app.EV2.y / 1000.0;
            th_EV2 = app.EV2.th;
            x_EV3  = app.EV3.x / 1000.0;
            y_EV3  = app.EV3.y / 1000.0;
            th_EV3 = app.EV3.th;
            
            % Update vehicle1 image location on track
            x_EV1 = x_EV1 + 2.37591; % Offset by x/y position at bottom left of track
            y_EV1 = y_EV1 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_EV1 = app.track_xoffset - app.track_EV_xoffset + x_EV1 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_EV1 = app.track_yoffset - app.track_EV_yoffset + y_EV1 * app.track_yscale;
            rotatedImage = imrotate(imread('EV1.png'), th_EV1, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.EV1_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.EV1_image.Position = [x_pixel_EV1, y_pixel_EV1, 24, 24]; % update EV image with new position
            
            % Update vehicle2 image location on track
            x_EV2 = x_EV2 + 2.37591; % Offset by x/y position at bottom left of track
            y_EV2 = y_EV2 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_EV2 = app.track_xoffset - app.track_EV_xoffset + x_EV2 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_EV2 = app.track_yoffset - app.track_EV_yoffset + y_EV2 * app.track_yscale;
            rotatedImage = imrotate(imread('EV2.png'), th_EV2, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.EV2_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.EV2_image.Position = [x_pixel_EV2, y_pixel_EV2, 24, 24]; % update EV image with new position
            
            % Update vehicle3 image location on track
            x_EV3 = x_EV3 + 2.37591; % Offset by x/y position at bottom left of track
            y_EV3 = y_EV3 + 0.29887; % Offset by x/y position at bottom left of track
            x_pixel_EV3 = app.track_xoffset - app.track_EV_xoffset + x_EV3 * app.track_xscale; % Convert to pixel location of center of vehicle image
            y_pixel_EV3 = app.track_yoffset - app.track_EV_yoffset + y_EV3 * app.track_yscale;
            rotatedImage = imrotate(imread('EV3.png'), th_EV3, "bilinear", "crop"); % Rotate image - TODO: try to find a way to rotate that looks better!
            app.EV3_image.ImageSource = rotatedImage; % update EV image with new rotation
            app.EV3_image.Position = [x_pixel_EV3, y_pixel_EV3, 24, 24]; % update EV image with new position
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupfcn(app)
            % Put the GUI in the center of the screen
            movegui(app.ECE756EV3GUIUIFigure,'center');
            
            % Initialize
            app.EV1 = EV(25000);
            app.EV2 = EV(25001);
            app.EV3 = EV(25002);
            
            % Initialize vehicle images
            app.EV1_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV1.png'), "Position", [500, 500, 24, 24]);
            app.EV2_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV2.png'), "Position", [550, 500, 24, 24]);
            app.EV3_image = uiimage(app.ECE756EV3GUIUIFigure, "ImageSource", imread('EV3.png'), "Position", [600, 500, 24, 24]);
            
            % Start callback timer, copied from 2020 iSpace code
            app.uiTimer.TimerFcn = @(~, ~)timerTick(app);
            start(app.uiTimer);
        end

        % Callback function: ECE756EV3GUIUIFigure, ExitMenu
        function ECE756EV3GUIUIFigureCloseRequest(app, event)
            disp('Shutting down')
            delete(app.uiTimer)
            delete(app)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'ECE756 EV3 Simulation Sandbox';'Spring 2021';'Demonstration of line following, platooning, and parking control logic';'';'David Fleissner';'Thomas Thorne' } ,'About','modal')
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            msgbox('TODO')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECE756EV3GUIUIFigure = uifigure('Visible', 'off');
            app.ECE756EV3GUIUIFigure.Name = 'ECE756 EV3 GUI';
            app.ECE756EV3GUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECE756EV3GUIUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ECE756EV3GUIUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ECE756EV3GUIUIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.ECE756EV3GUIUIFigure);
            app.HelpMenu.Text = 'Help';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TrackImage = uiimage(app.ECE756EV3GUIUIFigure);
            app.TrackImage.ImageSource = 'map.png';
            app.EVControlsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EVControlsPanel.Title = 'Vehicle Controls';
            app.LeaderButtonGroup = uibuttongroup(app.EVControlsPanel);
            app.LeaderButtonGroup.Title = 'Leader';
            app.EV1_LeaderButton = uiradiobutton(app.LeaderButtonGroup);
            app.EV1_LeaderButton.Text = 'Vehicle 1';
            app.EV1_LeaderButton.Value = true;
            app.EV2_LeaderButton = uiradiobutton(app.LeaderButtonGroup);
            app.EV2_LeaderButton.Text = 'Vehicle 2';
            app.EV3_LeaderButton = uiradiobutton(app.LeaderButtonGroup);
            app.EV3_LeaderButton.Text = 'Vehicle 3';
            app.EV4_LeaderButton = uiradiobutton(app.LeaderButtonGroup);
            app.EV4_LeaderButton.Text = 'Vehicle 4';
            app.NoteallothersarefollowersLabel = uilabel(app.EVControlsPanel);
            app.NoteallothersarefollowersLabel.Text = 'Note: all others are followers';
            app.EV1_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.EV1_DriveButton.Text = 'Drive';
            app.EV1_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.EV1_StopButton.Text = 'Stop';
            app.EV1_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.EV1_ParkButton.Text = 'Park';
            app.EV1_PlaceButton = uibutton(app.EVControlsPanel, 'push');
            app.EV1_PlaceButton.Text = 'Place';
            app.AllEV_ControlLabel = uilabel(app.EVControlsPanel);
            app.AllEV_ControlLabel.Text = 'All EVs';
            app.EV1_ControlLabel = uilabel(app.EVControlsPanel);
            app.EV1_ControlLabel.Text = 'EV1';
            app.AllEV_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.AllEV_DriveButton.Text = 'Drive';
            app.AllEV_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.AllEV_StopButton.Text = 'Stop';
            app.AllEV_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.AllEV_ParkButton.Text = 'Park';
            app.EV2_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.EV2_DriveButton.Text = 'Drive';
            app.EV2_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.EV2_StopButton.Text = 'Stop';
            app.EV2_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.EV2_ParkButton.Text = 'Park';
            app.EV2_PlaceButton = uibutton(app.EVControlsPanel, 'push');
            app.EV2_PlaceButton.Text = 'Place';
            app.EV2_ControlLabel = uilabel(app.EVControlsPanel);
            app.EV2_ControlLabel.Text = 'EV2';
            app.EV3_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.EV3_DriveButton.Text = 'Drive';
            app.EV3_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.EV3_StopButton.Text = 'Stop';
            app.EV3_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.EV3_ParkButton.Text = 'Park';
            app.EV3_PlaceButton = uibutton(app.EVControlsPanel, 'push');
            app.EV3_PlaceButton.Text = 'Place';
            app.EV3_ControlLabel = uilabel(app.EVControlsPanel);
            app.EV3_ControlLabel.Text = 'EV3';
            app.EV4_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.EV4_DriveButton.Text = 'Drive';
            app.EV4_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.EV4_StopButton.Text = 'Stop';
            app.EV4_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.EV4_ParkButton.Text = 'Park';
            app.EV4_PlaceButton = uibutton(app.EVControlsPanel, 'push');
            app.EV4_PlaceButton.Text = 'Place';
            app.EV4_ControlLabel = uilabel(app.EVControlsPanel);
            app.EV4_ControlLabel.Text = 'EV4';
            app.EV5_DriveButton = uibutton(app.EVControlsPanel, 'push');
            app.EV5_DriveButton.Text = 'Drive';
            app.EV5_StopButton = uibutton(app.EVControlsPanel, 'push');
            app.EV5_StopButton.Text = 'Stop';
            app.EV5_ParkButton = uibutton(app.EVControlsPanel, 'push');
            app.EV5_ParkButton.Text = 'Park';
            app.EV5_PlaceButton = uibutton(app.EVControlsPanel, 'push');
            app.EV5_PlaceButton.Text = 'Place';
            app.EV5_ControlLabel = uilabel(app.EVControlsPanel);
            app.EV5_ControlLabel.Text = 'EV5';
            app.EV2_DriveLamp = uilamp(app.EVControlsPanel);
            app.EV2_StopLamp = uilamp(app.EVControlsPanel);
            app.EV2_ParkLamp = uilamp(app.EVControlsPanel);
            app.EV4_DriveLamp = uilamp(app.EVControlsPanel);
            app.EV4_StopLamp = uilamp(app.EVControlsPanel);
            app.EV4_ParkLamp = uilamp(app.EVControlsPanel);
            app.EV1_DriveLamp = uilamp(app.EVControlsPanel);
            app.EV1_StopLamp = uilamp(app.EVControlsPanel);
            app.EV1_ParkLamp = uilamp(app.EVControlsPanel);
            app.EV3_DriveLamp = uilamp(app.EVControlsPanel);
            app.EV3_StopLamp = uilamp(app.EVControlsPanel);
            app.EV3_ParkLamp = uilamp(app.EVControlsPanel);
            app.EV5_DriveLamp = uilamp(app.EVControlsPanel);
            app.EV5_StopLamp = uilamp(app.EVControlsPanel);
            app.EV5_ParkLamp = uilamp(app.EVControlsPanel);
            app.ResetAllButton = uibutton(app.EVControlsPanel, 'push');
            app.ResetAllButton.Text = 'Reset All';
            app.EVScoresPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EVScoresPanel.Title = 'Vehicle Scores';
            app.EV1_ScoresLabel = uilabel(app.EVScoresPanel);
            app.EV1_ScoresLabel.Text = 'EV1';
            app.EV2_ScoresLabel = uilabel(app.EVScoresPanel);
            app.EV2_ScoresLabel.Text = 'EV2';
            app.EV3_ScoresLabel = uilabel(app.EVScoresPanel);
            app.EV3_ScoresLabel.Text = 'EV3';
            app.EV4_ScoresLabel = uilabel(app.EVScoresPanel);
            app.EV4_ScoresLabel.Text = 'EV4';
            app.EV1_C1 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV1_C1.ValueDisplayFormat = '%5.1f';
            app.EV1_C1Label = uilabel(app.EVScoresPanel);
            app.EV1_C1Label.Text = 'C1';
            app.EV1_C3 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV1_C3.ValueDisplayFormat = '%3.1f';
            app.EV1_C3Label = uilabel(app.EVScoresPanel);
            app.EV1_C3Label.Text = 'C3';
            app.EV1_C2 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV1_C2.ValueDisplayFormat = '%3.1f';
            app.EV1_C2Label = uilabel(app.EVScoresPanel);
            app.EV1_C2Label.Text = 'C2';
            app.EV2_C1 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV2_C1.ValueDisplayFormat = '%5.1f';
            app.EV2_C1Label = uilabel(app.EVScoresPanel);
            app.EV2_C1Label.Text = 'C1';
            app.EV2_C3 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV2_C3.ValueDisplayFormat = '%3.1f';
            app.EV2_C3Label = uilabel(app.EVScoresPanel);
            app.EV2_C3Label.Text = 'C3';
            app.EV2_C2 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV2_C2.ValueDisplayFormat = '%3.1f';
            app.EV2_C2Label = uilabel(app.EVScoresPanel);
            app.EV2_C2Label.Text = 'C2';
            app.EV3_C1 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV3_C1.ValueDisplayFormat = '%5.1f';
            app.EV3_C1Label = uilabel(app.EVScoresPanel);
            app.EV3_C1Label.Text = 'C1';
            app.EV3_C3 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV3_C3.ValueDisplayFormat = '%3.1f';
            app.EV3_C3Label = uilabel(app.EVScoresPanel);
            app.EV3_C3Label.Text = 'C3';
            app.EV3_C2 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV3_C2.ValueDisplayFormat = '%3.1f';
            app.EV3_C2Label = uilabel(app.EVScoresPanel);
            app.EV3_C2Label.Text = 'C2';
            app.EV4_C1 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV4_C1.ValueDisplayFormat = '%5.1f';
            app.EV4_C1Label = uilabel(app.EVScoresPanel);
            app.EV4_C1Label.Text = 'C1';
            app.EV4_C3 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV4_C3.ValueDisplayFormat = '%3.1f';
            app.EV4_C3Label = uilabel(app.EVScoresPanel);
            app.EV4_C3Label.Text = 'C3';
            app.EV4_C2 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV4_C2.ValueDisplayFormat = '%3.1f';
            app.EV4_C2Label = uilabel(app.EVScoresPanel);
            app.EV4_C2Label.Text = 'C2';
            app.EV5_ScoresLabel = uilabel(app.EVScoresPanel);
            app.EV5_ScoresLabel.Text = 'EV5';
            app.EV5_C1 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV5_C1.ValueDisplayFormat = '%5.1f';
            app.EV5_C1Label = uilabel(app.EVScoresPanel);
            app.EV5_C1Label.Text = 'C1';
            app.EV5_C3 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV5_C3.ValueDisplayFormat = '%3.1f';
            app.EV5_C3Label = uilabel(app.EVScoresPanel);
            app.EV5_C3Label.Text = 'C3';
            app.EV5_C2 = uieditfield(app.EVScoresPanel, 'numeric');
            app.EV5_C2.ValueDisplayFormat = '%3.1f';
            app.EV5_C2Label = uilabel(app.EVScoresPanel);
            app.EV5_C2Label.Text = 'C2';
            app.EVPositionsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EVPositionsPanel.Title = 'Vehicle Positions';
            app.EV1_PositionsLabel = uilabel(app.EVPositionsPanel);
            app.EV1_PositionsLabel.Text = 'EV1';
            app.EV2_PositionsLabel = uilabel(app.EVPositionsPanel);
            app.EV2_PositionsLabel.Text = 'EV2';
            app.EV3_PositionsLabel = uilabel(app.EVPositionsPanel);
            app.EV3_PositionsLabel.Text = 'EV3';
            app.EV4_PositionsLabel = uilabel(app.EVPositionsPanel);
            app.EV4_PositionsLabel.Text = 'EV4';
            app.EV1_X = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV1_X.ValueDisplayFormat = '%5.1f';
            app.EV1_XmmLabel = uilabel(app.EVPositionsPanel);
            app.EV1_XmmLabel.Text = 'X (mm)';
            app.EV1_Y = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV1_Y.ValueDisplayFormat = '%5.1f';
            app.EV1_YmmLabel = uilabel(app.EVPositionsPanel);
            app.EV1_YmmLabel.Text = 'Y (mm)';
            app.EV1_TH = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV1_TH.ValueDisplayFormat = '%3.1f';
            app.EV1_YawdegLabel = uilabel(app.EVPositionsPanel);
            app.EV1_YawdegLabel.Text = 'Yaw (°)';
            app.EV2_X = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV2_X.ValueDisplayFormat = '%5.1f';
            app.EV2_XmmLabel = uilabel(app.EVPositionsPanel);
            app.EV2_XmmLabel.Text = 'X (mm)';
            app.EV2_Y = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV2_Y.ValueDisplayFormat = '%5.1f';
            app.EV2_YmmLabel = uilabel(app.EVPositionsPanel);
            app.EV2_YmmLabel.Text = 'Y (mm)';
            app.EV2_TH = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV2_TH.ValueDisplayFormat = '%3.1f';
            app.EV2_YawdegLabel = uilabel(app.EVPositionsPanel);
            app.EV2_YawdegLabel.Text = 'Yaw (°)';
            app.EV3_X = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV3_X.ValueDisplayFormat = '%5.1f';
            app.EV3_XmmLabel = uilabel(app.EVPositionsPanel);
            app.EV3_XmmLabel.Text = 'X (mm)';
            app.EV3_Y = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV3_Y.ValueDisplayFormat = '%5.1f';
            app.EV3_YmmLabel = uilabel(app.EVPositionsPanel);
            app.EV3_YmmLabel.Text = 'Y (mm)';
            app.EV3_TH = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV3_TH.ValueDisplayFormat = '%3.1f';
            app.EV3_YawdegLabel = uilabel(app.EVPositionsPanel);
            app.EV3_YawdegLabel.Text = 'Yaw (°)';
            app.EV4_X = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV4_X.ValueDisplayFormat = '%5.1f';
            app.EV4_XmmLabel = uilabel(app.EVPositionsPanel);
            app.EV4_XmmLabel.Text = 'X (mm)';
            app.EV4_Y = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV4_Y.ValueDisplayFormat = '%5.1f';
            app.EV4_YmmLabel = uilabel(app.EVPositionsPanel);
            app.EV4_YmmLabel.Text = 'Y (mm)';
            app.EV4_TH = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV4_TH.ValueDisplayFormat = '%3.1f';
            app.EV4_YawdegLabel = uilabel(app.EVPositionsPanel);
            app.EV4_YawdegLabel.Text = 'Yaw (°)';
            app.EV5_PositionsLabel = uilabel(app.EVPositionsPanel);
            app.EV5_PositionsLabel.Text = 'EV5';
            app.EV5_X = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV5_X.ValueDisplayFormat = '%5.1f';
            app.EV5_XmmLabel = uilabel(app.EVPositionsPanel);
            app.EV5_XmmLabel.Text = 'X (mm)';
            app.EV5_Y = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV5_Y.ValueDisplayFormat = '%5.1f';
            app.EV5_YmmLabel = uilabel(app.EVPositionsPanel);
            app.EV5_YmmLabel.Text = 'Y (mm)';
            app.EV5_TH = uieditfield(app.EVPositionsPanel, 'numeric');
            app.EV5_TH.ValueDisplayFormat = '%3.1f';
            app.EV5_YawdegLabel = uilabel(app.EVPositionsPanel);
            app.EV5_YawdegLabel.Text = 'Yaw (°)';
            app.EV1_SensorsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EV1_SensorsPanel.Title = 'EV1 Sensors';
            app.RightRLILabel_4 = uilabel(app.EV1_SensorsPanel);
            app.RightRLILabel_4.Text = 'Right RLI';
            app.EV1_RightRLIGauge = uigauge(app.EV1_SensorsPanel, 'linear');
            app.LeftRLILabel_4 = uilabel(app.EV1_SensorsPanel);
            app.LeftRLILabel_4.Text = 'Left RLI';
            app.EV1_LeftRLIGauge = uigauge(app.EV1_SensorsPanel, 'linear');
            app.UltrasonicLabel = uilabel(app.EV1_SensorsPanel);
            app.UltrasonicLabel.Text = 'Ultrasonic';
            app.EV1_UltrasonicGauge = uigauge(app.EV1_SensorsPanel, 'linear');
            app.EV1_UltrasonicGauge.Limits = [0 255];
            app.EV1_UltrasonicGauge.MajorTicks = [0 64 128 192 255];
            app.EV1_UltrasonicValue = uilabel(app.EV1_SensorsPanel);
            app.EV1_UltrasonicValue.Text = '255';
            app.EV1_LeftRLIValue = uilabel(app.EV1_SensorsPanel);
            app.EV1_LeftRLIValue.Text = '100';
            app.EV1_RightRLIValue = uilabel(app.EV1_SensorsPanel);
            app.EV1_RightRLIValue.Text = '100';
            app.BatteryGaugeLabel = uilabel(app.EV1_SensorsPanel);
            app.BatteryGaugeLabel.Text = 'Battery';
            app.EV1_BatteryGauge = uigauge(app.EV1_SensorsPanel, 'linear');
            app.EV1_BatteryGauge.Limits = [7000 8500];
            app.EV1_BatteryGauge.MajorTicks = [7000 7750 8500];
            app.EV1_mVLabel = uilabel(app.EV1_SensorsPanel);
            app.EV1_mVLabel.Text = 'mV';
            app.EV1_cmLabel = uilabel(app.EV1_SensorsPanel);
            app.EV1_cmLabel.Text = 'cm';
            app.EV1_PercentLabel2 = uilabel(app.EV1_SensorsPanel);
            app.EV1_PercentLabel2.Text = '%';
            app.EV1_PercentLabel1 = uilabel(app.EV1_SensorsPanel);
            app.EV1_PercentLabel1.Text = '%';
            app.EV2_SensorsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EV2_SensorsPanel.Title = 'EV2 Sensors';
            app.RightRLILabel_5 = uilabel(app.EV2_SensorsPanel);
            app.RightRLILabel_5.Text = 'Right RLI';
            app.EV2_RightRLIGauge = uigauge(app.EV2_SensorsPanel, 'linear');
            app.LeftRLILabel_5 = uilabel(app.EV2_SensorsPanel);
            app.LeftRLILabel_5.Text = 'Left RLI';
            app.EV2_LeftRLIGauge = uigauge(app.EV2_SensorsPanel, 'linear');
            app.UltrasonicLabel_2 = uilabel(app.EV2_SensorsPanel);
            app.UltrasonicLabel_2.Text = 'Ultrasonic';
            app.EV2_UltrasonicGauge = uigauge(app.EV2_SensorsPanel, 'linear');
            app.EV2_UltrasonicGauge.Limits = [0 255];
            app.EV2_UltrasonicGauge.MajorTicks = [0 64 128 192 255];
            app.EV2_UltrasonicValue = uilabel(app.EV2_SensorsPanel);
            app.EV2_UltrasonicValue.Text = '255';
            app.EV2_LeftRLIValue = uilabel(app.EV2_SensorsPanel);
            app.EV2_LeftRLIValue.Text = '100';
            app.EV2_RightRLIValue = uilabel(app.EV2_SensorsPanel);
            app.EV2_RightRLIValue.Text = '100';
            app.BatteryGauge_2Label = uilabel(app.EV2_SensorsPanel);
            app.BatteryGauge_2Label.Text = 'Battery';
            app.EV2_BatteryGauge = uigauge(app.EV2_SensorsPanel, 'linear');
            app.EV2_BatteryGauge.Limits = [7000 8500];
            app.EV2_BatteryGauge.MajorTicks = [7000 7750 8500];
            app.EV2_mVLabel = uilabel(app.EV2_SensorsPanel);
            app.EV2_mVLabel.Text = 'mV';
            app.EV2_cmLabel = uilabel(app.EV2_SensorsPanel);
            app.EV2_cmLabel.Text = 'cm';
            app.EV2_PercentLabel2 = uilabel(app.EV2_SensorsPanel);
            app.EV2_PercentLabel2.Text = '%';
            app.EV2_PercentLabel1 = uilabel(app.EV2_SensorsPanel);
            app.EV2_PercentLabel1.Text = '%';
            app.EV3_SensorsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EV3_SensorsPanel.Title = 'EV3 Sensors';
            app.RightRLILabel_6 = uilabel(app.EV3_SensorsPanel);
            app.RightRLILabel_6.Text = 'Right RLI';
            app.EV3_RightRLIGauge = uigauge(app.EV3_SensorsPanel, 'linear');
            app.LeftRLILabel_6 = uilabel(app.EV3_SensorsPanel);
            app.LeftRLILabel_6.Text = 'Left RLI';
            app.EV3_LeftRLIGauge = uigauge(app.EV3_SensorsPanel, 'linear');
            app.UltrasonicLabel_3 = uilabel(app.EV3_SensorsPanel);
            app.UltrasonicLabel_3.Text = 'Ultrasonic';
            app.EV3_UltrasonicGauge = uigauge(app.EV3_SensorsPanel, 'linear');
            app.EV3_UltrasonicGauge.Limits = [0 255];
            app.EV3_UltrasonicGauge.MajorTicks = [0 64 128 192 255];
            app.EV3_UltrasonicValue = uilabel(app.EV3_SensorsPanel);
            app.EV3_UltrasonicValue.Text = '255';
            app.EV3_LeftRLIValue = uilabel(app.EV3_SensorsPanel);
            app.EV3_LeftRLIValue.Text = '100';
            app.EV3_RightRLIValue = uilabel(app.EV3_SensorsPanel);
            app.EV3_RightRLIValue.Text = '100';
            app.BatteryGauge_3Label = uilabel(app.EV3_SensorsPanel);
            app.BatteryGauge_3Label.Text = 'Battery';
            app.EV3_BatteryGauge = uigauge(app.EV3_SensorsPanel, 'linear');
            app.EV3_BatteryGauge.Limits = [7000 8500];
            app.EV3_BatteryGauge.MajorTicks = [7000 7750 8500];
            app.EV3_mVLabel = uilabel(app.EV3_SensorsPanel);
            app.EV3_mVLabel.Text = 'mV';
            app.EV3_cmLabel = uilabel(app.EV3_SensorsPanel);
            app.EV3_cmLabel.Text = 'cm';
            app.EV3_PercentLabel2 = uilabel(app.EV3_SensorsPanel);
            app.EV3_PercentLabel2.Text = '%';
            app.EV3_PercentLabel1 = uilabel(app.EV3_SensorsPanel);
            app.EV3_PercentLabel1.Text = '%';
            app.EV4_SensorsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EV4_SensorsPanel.Title = 'EV4 Sensors';
            app.RightRLILabel_7 = uilabel(app.EV4_SensorsPanel);
            app.RightRLILabel_7.Text = 'Right RLI';
            app.EV4_RightRLIGauge = uigauge(app.EV4_SensorsPanel, 'linear');
            app.LeftRLILabel_7 = uilabel(app.EV4_SensorsPanel);
            app.LeftRLILabel_7.Text = 'Left RLI';
            app.EV4_LeftRLIGauge = uigauge(app.EV4_SensorsPanel, 'linear');
            app.UltrasonicLabel_4 = uilabel(app.EV4_SensorsPanel);
            app.UltrasonicLabel_4.Text = 'Ultrasonic';
            app.EV4_UltrasonicGauge = uigauge(app.EV4_SensorsPanel, 'linear');
            app.EV4_UltrasonicGauge.Limits = [0 255];
            app.EV4_UltrasonicGauge.MajorTicks = [0 64 128 192 255];
            app.EV4_UltrasonicValue = uilabel(app.EV4_SensorsPanel);
            app.EV4_UltrasonicValue.Text = '255';
            app.EV4_LeftRLIValue = uilabel(app.EV4_SensorsPanel);
            app.EV4_LeftRLIValue.Text = '100';
            app.EV4_RightRLIValue = uilabel(app.EV4_SensorsPanel);
            app.EV4_RightRLIValue.Text = '100';
            app.BatteryGauge_4Label = uilabel(app.EV4_SensorsPanel);
            app.BatteryGauge_4Label.Text = 'Battery';
            app.EV4_BatteryGauge = uigauge(app.EV4_SensorsPanel, 'linear');
            app.EV4_BatteryGauge.Limits = [7000 8500];
            app.EV4_BatteryGauge.MajorTicks = [7000 7750 8500];
            app.EV4_mVLabel = uilabel(app.EV4_SensorsPanel);
            app.EV4_mVLabel.Text = 'mV';
            app.EV4_cmLabel = uilabel(app.EV4_SensorsPanel);
            app.EV4_cmLabel.Text = 'cm';
            app.EV4_PercentLabel2 = uilabel(app.EV4_SensorsPanel);
            app.EV4_PercentLabel2.Text = '%';
            app.EV4_PercentLabel1 = uilabel(app.EV4_SensorsPanel);
            app.EV4_PercentLabel1.Text = '%';
            app.EV5_SensorsPanel = uipanel(app.ECE756EV3GUIUIFigure);
            app.EV5_SensorsPanel.Title = 'EV5 Sensors';
            app.RightRLILabel_8 = uilabel(app.EV5_SensorsPanel);
            app.RightRLILabel_8.Text = 'Right RLI';
            app.EV5_RightRLIGauge = uigauge(app.EV5_SensorsPanel, 'linear');
            app.LeftRLILabel_8 = uilabel(app.EV5_SensorsPanel);
            app.LeftRLILabel_8.Text = 'Left RLI';
            app.EV5_LeftRLIGauge = uigauge(app.EV5_SensorsPanel, 'linear');
            app.UltrasonicLabel_5 = uilabel(app.EV5_SensorsPanel);
            app.UltrasonicLabel_5.Text = 'Ultrasonic';
            app.EV5_UltrasonicGauge = uigauge(app.EV5_SensorsPanel, 'linear');
            app.EV5_UltrasonicGauge.Limits = [0 255];
            app.EV5_UltrasonicGauge.MajorTicks = [0 64 128 192 255];
            app.EV5_UltrasonicValue = uilabel(app.EV5_SensorsPanel);
            app.EV5_UltrasonicValue.Text = '255';
            app.EV5_LeftRLIValue = uilabel(app.EV5_SensorsPanel);
            app.EV5_LeftRLIValue.Text = '100';
            app.EV5_RightRLIValue = uilabel(app.EV5_SensorsPanel);
            app.EV5_RightRLIValue.Text = '100';
            app.BatteryGauge_5Label = uilabel(app.EV5_SensorsPanel);
            app.BatteryGauge_5Label.Text = 'Battery';
            app.EV5_BatteryGauge = uigauge(app.EV5_SensorsPanel, 'linear');
            app.EV5_BatteryGauge.Limits = [7000 8500];
            app.EV5_BatteryGauge.MajorTicks = [7000 7750 8500];
            app.EV5_mVLabel = uilabel(app.EV5_SensorsPanel);
            app.EV5_mVLabel.Text = 'mV';
            app.EV5_cmLabel = uilabel(app.EV5_SensorsPanel);
            app.EV5_cmLabel.Text = 'cm';
            app.EV5_PercentLabel2 = uilabel(app.EV5_SensorsPanel);
            app.EV5_PercentLabel2.Text = '%';
            app.EV5_PercentLabel1 = uilabel(app.EV5_SensorsPanel);
            app.EV5_PercentLabel1.Text = '%';
            % Show the figure after all components are created
            app.ECE756EV3GUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EV3SimGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECE756EV3GUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupfcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECE756EV3GUIUIFigure)
        end
    end
end